classdef SegmentationTool < matlab.apps.AppBase
    %SEGMENTATIONTOOL LoadImage

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Select2Button               matlab.ui.control.Button
        Select1Button               matlab.ui.control.Button
        TumorByGold_stdLabel        matlab.ui.control.Label
        BrainMRIImageLabel          matlab.ui.control.Label
        SementedwithMTSmethodPanel  matlab.ui.container.Panel
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        TanimotoEditField           matlab.ui.control.NumericEditField
        TanimotoLabel               matlab.ui.control.Label
        DiceEditField               matlab.ui.control.NumericEditField
        DiceLabel                   matlab.ui.control.Label
        MTSButton                   matlab.ui.control.Button
        RGrowingButton              matlab.ui.control.Button
        PreprocessingButton         matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        SegmentationToolLabel       matlab.ui.control.Label
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IM 
        IM_skullfree
        B3
        B4
        RGB_IM
        Q_IM
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
          
     %On\off
app.UIAxes.Visible='on';
app.PreprocessingButton.Enable='on';
app.BrainMRIImageLabel.Visible='on';

%%%Caricamento immagine da segmentare:
[file,~]=uigetfile('*.dcm','C:\Users\Vincenzo\Documents\MATLAB\SADT');
app.IM=dicomread(file);
app.IM=mat2gray(app.IM);      
imshow(app.IM,'Parent',app.UIAxes)


        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
         
          %On\off
app.MTSButton.Enable='on';
app.RGrowingButton.Enable='on';  

%Pre-Processing e Skull Stripping
app.IM=medfilt2(app.IM);
IM_ms=imsharpen(app.IM,'Radius',2,'Amount',1.58); 
T0=graythresh(IM_ms); 
[app.IM_skullfree,~,~]=Skull_remove(IM_ms,T0);
imshow(app.IM_skullfree,'Parent',app.UIAxes)


        end

        % Button pushed function: MTSButton
        function MTSButtonPushed(app, event)

%On\Off
app.RGrowingButton.Enable='off';
app.SementedwithMTSmethodPanel.Visible='on';
app.Select1Button.Visible='on';
app.Select2Button.Visible='on';

%Segmentazione MTS:
T=multithresh(app.IM_skullfree,4); 
B1=app.IM_skullfree>T(1);
B2=app.IM_skullfree>T(2);
app.B3=app.IM_skullfree>T(3);
app.B4=app.IM_skullfree>T(4);

app.Q_IM=imquantize(app.IM_skullfree,T); %Creazione di una mappa sovrapposta delle varie immagini segmentate.
app.RGB_IM=label2rgb(app.Q_IM);

imshow(B1,'Parent',app.UIAxes2)
imshow(B2,'Parent',app.UIAxes3)
imshow(app.B3,'Parent',app.UIAxes4)
imshow(app.B4,'Parent',app.UIAxes5)
        end

        % Button pushed function: Select1Button
        function Select1ButtonPushed(app, event)
%Inizializzo a zero i coeff per cancellare eventuali dati gia memorizzati
app.DiceEditField.Value=0;
app.TanimotoEditField.Value=0;

 % ON\OFF
 app.SementedwithMTSmethodPanel.Visible='off';
 app.Select1Button.Visible='off';
 app.Select2Button.Visible='off';           
 app.DiceEditField.Visible='on';
 app.TanimotoEditField.Visible='on';
 app.DiceLabel.Visible='on';
 app.TanimotoLabel.Visible='on';
 app.RGrowingButton.Enable='on';
 app.TumorByGold_stdLabel.Visible='on';
            
% Estrazione del Tumore e Calcolo dei coefficienti
 BW_tumor1 =bwareafilt(app.B3,1);
 [name,~]=uigetfile('*.mat','C:\Users\Vincenzo\Documents\MATLAB\SADT\Tesina SADT');
 Gold_std=load(name); 
 Gold_std=struct2cell(Gold_std);
 Gold_std=cell2mat(Gold_std);
 imshow(app.RGB_IM,'Parent',app.UIAxes)
 app.BrainMRIImageLabel.Text='Tumor Extracted';
 imshow(Gold_std,'Parent',app.UIAxes6)
 num1=2*nnz(BW_tumor1 & Gold_std);
 den1= nnz(BW_tumor1)+nnz(Gold_std);
 Dice=(num1/den1)*100; %valore percentuale
 num2=num1/2;
 den2= den1-nnz(BW_tumor1&Gold_std);
 Tanimoto=(num2/den2)*100; %valore percentuale
 app.DiceEditField.Value=round(Dice);
 app.TanimotoEditField.Value=round(Tanimoto);
 

 clear num1 den1 num2 den2
            
           
        end

        % Button pushed function: Select2Button
        function Select2ButtonPushed(app, event)
       
 %Inizializzo a zero i coeff per cancellare eventuali dati gia memorizzati
app.DiceEditField.Value=0;
app.TanimotoEditField.Value=0;

 %ON\OFF           
 app.SementedwithMTSmethodPanel.Visible='off';
 app.Select1Button.Visible='off';
 app.Select2Button.Visible='off';           
 app.DiceEditField.Visible='on';
 app.TanimotoEditField.Visible='on';
 app.DiceLabel.Visible='on';
 app.TanimotoLabel.Visible='on';
 app.RGrowingButton.Enable='on';
 app.TumorByGold_stdLabel.Visible='on';
  
% Estrazione del Tumore e Calcolo dei coefficienti
 BW_tumor1 =bwareafilt(app.B4,1);
 [name,~]=uigetfile('*.mat','C:\Users\Vincenzo\Documents\MATLAB\SADT\Tesina SADT');
 Gold_std=load(name); 
 Gold_std=struct2cell(Gold_std);
 Gold_std=cell2mat(Gold_std);
 imshow(app.RGB_IM,'Parent',app.UIAxes)
 app.BrainMRIImageLabel.Text='Tumor Extracted';
 imshow(Gold_std,'Parent',app.UIAxes6)
 num1=2*nnz(BW_tumor1 & Gold_std);
 den1= nnz(BW_tumor1)+nnz(Gold_std);
 Dice=(num1/den1)*100; %valore percentuale
 num2=num1/2;
 den2= den1-nnz(BW_tumor1&Gold_std);
 Tanimoto=(num2/den2)*100; %valore percentuale
 app.DiceEditField.Value=round(Dice);
 app.TanimotoEditField.Value=round(Tanimoto);


 clear num1 den1 num2 den2


        end

        % Button pushed function: RGrowingButton
        function RGrowingButtonPushed(app, event)

%Inizializzo a zero i coeff per cancellare eventuali dati gia memorizzati
 app.DiceEditField.Value=0;
 app.TanimotoEditField.Value=0;

  % On\OFF          
  app.DiceEditField.Visible='on';
  app.TanimotoEditField.Visible='on';
  app.DiceLabel.Visible='on';
  app.TanimotoLabel.Visible='on';


  % Region Growing Code          
  imshow(app.IM_skullfree,'Parent',app.UIAxes)
  roi = drawpoint(app.UIAxes);
  y = roi.Position(1);
  x = roi.Position(2);
  x=round(x); y=round(y);
  app.MTSButton.Enable='off'; %Solo dopo la scelta della ROI posso cambiare metodo!
  T2=0.10;   
  BW_tumor2=Region_growing(app.IM_skullfree,x,y,T2);
  [name,~]=uigetfile('*.mat','C:\Users\Vincenzo\Documents\MATLAB\SADT\Tesina SADT');
  Gold_std=load(name); 
  Gold_std=struct2cell(Gold_std);
  Gold_std=cell2mat(Gold_std);
  imshow(Gold_std,'Parent',app.UIAxes6)
  imshow(app.IM_skullfree+BW_tumor2,'Parent',app.UIAxes)
  num1=2*nnz(BW_tumor2&Gold_std);
  den1= nnz(BW_tumor2)+nnz(Gold_std);
  Dice=(num1/den1)*100; %valore percentuale
  num2=num1/2;
  den2= den1-nnz(BW_tumor2 & Gold_std);
  Tanimoto=(num2/den2)*100; %valore percentuale
  app.DiceEditField.Value=round(Dice);
  app.TanimotoEditField.Value=round(Tanimoto);
  clear num1 den1 num2 den2
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RMI BRAIN IMAGE')
            app.UIAxes.Visible = 'off';
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.Visible = 'off';
            app.SegmentationToolLabel = uilabel(app.UIFigure);
            app.SegmentationToolLabel.Text = 'Segmentation Tool';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.PreprocessingButton = uibutton(app.UIFigure, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Enable = 'off';
            app.PreprocessingButton.Text = 'Preprocessing';
            app.RGrowingButton = uibutton(app.UIFigure, 'push');
            app.RGrowingButton.ButtonPushedFcn = createCallbackFcn(app, @RGrowingButtonPushed, true);
            app.RGrowingButton.Enable = 'off';
            app.RGrowingButton.Text = 'RGrowing';
            app.MTSButton = uibutton(app.UIFigure, 'push');
            app.MTSButton.ButtonPushedFcn = createCallbackFcn(app, @MTSButtonPushed, true);
            app.MTSButton.Enable = 'off';
            app.MTSButton.Text = 'MTS';
            app.DiceLabel = uilabel(app.UIFigure);
            app.DiceLabel.Visible = 'off';
            app.DiceLabel.Text = 'Dice [%]';
            app.DiceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiceEditField.Visible = 'off';
            app.TanimotoLabel = uilabel(app.UIFigure);
            app.TanimotoLabel.Visible = 'off';
            app.TanimotoLabel.Text = ' Tanimoto [%]';
            app.TanimotoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TanimotoEditField.Visible = 'off';
            app.SementedwithMTSmethodPanel = uipanel(app.UIFigure);
            app.SementedwithMTSmethodPanel.Title = 'Semented with MTS method';
            app.SementedwithMTSmethodPanel.Visible = 'off';
            app.UIAxes5 = uiaxes(app.SementedwithMTSmethodPanel);
            title(app.UIAxes5, 'T3')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.Visible = 'off';
            app.UIAxes4 = uiaxes(app.SementedwithMTSmethodPanel);
            title(app.UIAxes4, 'T4')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.UIAxes2 = uiaxes(app.SementedwithMTSmethodPanel);
            title(app.UIAxes2, 'T1')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.SementedwithMTSmethodPanel);
            title(app.UIAxes3, 'T2')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.BrainMRIImageLabel = uilabel(app.UIFigure);
            app.BrainMRIImageLabel.Visible = 'off';
            app.BrainMRIImageLabel.Text = 'Brain MRI Image';
            app.TumorByGold_stdLabel = uilabel(app.UIFigure);
            app.TumorByGold_stdLabel.Visible = 'off';
            app.TumorByGold_stdLabel.Text = 'Tumor By Gold_std';
            app.Select1Button = uibutton(app.UIFigure, 'push');
            app.Select1Button.ButtonPushedFcn = createCallbackFcn(app, @Select1ButtonPushed, true);
            app.Select1Button.Visible = 'off';
            app.Select1Button.Text = 'Select1';
            app.Select2Button = uibutton(app.UIFigure, 'push');
            app.Select2Button.ButtonPushedFcn = createCallbackFcn(app, @Select2ButtonPushed, true);
            app.Select2Button.Visible = 'off';
            app.Select2Button.Text = 'Select2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end